classdef DatePicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FechaDatePickerLabel  matlab.ui.control.Label
        FechaDatePicker       matlab.ui.control.DatePicker
        Label                 matlab.ui.control.Label
    end

    methods (Access = private)

        % Value changed function: FechaDatePicker
        function FechaDatePickerValueChanged(app, event)
            value = app.FechaDatePicker.Value %Obtenemos el valor temporal seleccionado
            s = datestr(value); %valor temporal seleccionado en formato de string
            y = num2str(year(value)); %Año del valor seleccionado
            m = cell2mat(month(value, 'name')); %Nombre del mes del valor seleccionado
            d = num2str(day(value)); %Día del valor seleccionado
            dname = cell2mat(day(value,'name')); %Nombre del día del valor seleccionado
            
            txt = [dname, ' ', d, 'th of ', m, ', ', y]; %Texto informativo
            app.Label.Text = txt; 
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FechaDatePickerLabel = uilabel(app.UIFigure);
            app.FechaDatePickerLabel.Text = 'Fecha';
            app.FechaDatePicker = uidatepicker(app.UIFigure);
            app.FechaDatePicker.Limits = [datetime([2019 1 1]) datetime([2020 12 31])];
            app.FechaDatePicker.DisabledDates = [datetime([2020 10 31]); datetime([2020 12 25])];
            app.FechaDatePicker.ValueChangedFcn = createCallbackFcn(app, @FechaDatePickerValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DatePicker

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end