classdef Slider < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Label_4              matlab.ui.control.Label
        Label_5              matlab.ui.control.Label
        Label_6              matlab.ui.control.Label
        Label_7              matlab.ui.control.Label
        Bateria              matlab.ui.control.Label
        deBateraSliderLabel  matlab.ui.control.Label
        BateraSlider         matlab.ui.control.Slider
    end

    
    properties (Access = private)
        s % Dimensiones completas de app.Bateria
        h % Alto en pixeles de app.Batería
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Abrimos la GUI y recogemos el alto en pixeles de app.Bateria
            app.s = app.Bateria.Position;
            app.h = app.s(4);
        end

        % Value changed function: BateraSlider
        function BateraSliderValueChanged(app, event)
            value = app.BateraSlider.Value;  %Recogemos el valor en el slider
            h2 = (value * app.h) / 100; %Equivalencia de value para el porcentaje de bateria.
            app.Bateria.Position = [app.s(1), app.s(2), app.s(3), h2]; %Modificamos el alto de app.Bateria
            
            %Formato condicional de color de la bateria según su porcentaje
            if value > 50
                app.Bateria.BackgroundColor = [0, 1, 0];
            elseif value <= 50 && value >= 20
                app.Bateria.BackgroundColor = [1, 1, 0];
            elseif value < 20
                app.Bateria.BackgroundColor = [1, 0, 0];
            end
            
        end

        % Value changing function: BateraSlider
        function BateraSliderValueChanging(app, event)
            changingValue = event.Value; %Recogemos el valor en el slider
            h2 = (changingValue * app.h) / 100; %Equivalencia de value para el porcentaje de bateria.
            app.Bateria.Position = [app.s(1), app.s(2), app.s(3), h2]; %Modificamos el alto de app.Bateria
            
            %Formato condicional de color de la bateria según su porcentaje
            if changingValue > 50
                app.Bateria.BackgroundColor = [0, 1, 0];
            elseif changingValue <= 50 && changingValue >= 20
                app.Bateria.BackgroundColor = [1, 1, 0];
            elseif changingValue < 20
                app.Bateria.BackgroundColor = [1, 0, 0];
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = {''; ''};
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = {''; ''};
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.Bateria = uilabel(app.UIFigure);
            app.Bateria.Text = '';
            app.deBateraSliderLabel = uilabel(app.UIFigure);
            app.deBateraSliderLabel.Text = '% de Batería';
            app.BateraSlider = uislider(app.UIFigure);
            app.BateraSlider.ValueChangedFcn = createCallbackFcn(app, @BateraSliderValueChanged, true);
            app.BateraSlider.ValueChangingFcn = createCallbackFcn(app, @BateraSliderValueChanging, true);
            app.BateraSlider.Tooltip = {'Deslizame!'};
            app.BateraSlider.Value = 100;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Slider

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end