classdef Spinner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        fxSpinnerLabel  matlab.ui.control.Label
        fxSpinner       matlab.ui.control.Spinner
        UIAxes          matlab.ui.control.UIAxes
        fySpinnerLabel  matlab.ui.control.Label
        fySpinner       matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        fx %Frecuencia de onda para x.
        fy %Frecuencia de onda para y.
        t  %Variable temporal para ecuaciones paramétricas.
        graph %Identificador para la figura de Lissajous dibujada.
    end
    
    methods (Access = private)
        
        function results = Graficar(app)
            %Actualización de la gráfica conforme se modifican las frecuencias de onda.
            app.graph.XData = sind(app.fx * app.t); 
            app.graph.YData = sind(app.fy * app.t);
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Inicialización de todas las propiedades.
            app.fx = 1;
            app.fy = 1;
            app.t = linspace(0,360,360);
            app.graph = line(app.UIAxes,sind(0:360), sind(0:360));
        end

        % Value changed function: fxSpinner
        function fxSpinnerValueChanged(app, event)
%             app.fx = app.fxSpinner.Value;
%             Graficar(app)
        end

        % Callback function
        function fySpinnerValueChanged(app, event)
%             app.fy = app.fySpinner.Value;
%             Graficar(app)
            
        end

        % Value changing function: fxSpinner
        function fxSpinnerValueChanging(app, event)
            app.fx = event.Value;
            Graficar(app)
            
        end

        % Callback function
        function fySpinnerValueChanged2(app, event)
            
        end

        % Value changing function: fySpinner
        function fySpinnerValueChanging(app, event)
            app.fy = event.Value;
            Graficar(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.fxSpinnerLabel = uilabel(app.UIFigure);
            app.fxSpinnerLabel.Text = 'fx';
            app.fxSpinner = uispinner(app.UIFigure);
            app.fxSpinner.Step = 0.05;
            app.fxSpinner.ValueChangingFcn = createCallbackFcn(app, @fxSpinnerValueChanging, true);
            app.fxSpinner.Limits = [0 10];
            app.fxSpinner.ValueDisplayFormat = '%5.2f U';
            app.fxSpinner.ValueChangedFcn = createCallbackFcn(app, @fxSpinnerValueChanged, true);
            app.fxSpinner.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [-1.5 1.5];
            app.UIAxes.YLim = [-1.5 1.5];
            app.fySpinnerLabel = uilabel(app.UIFigure);
            app.fySpinnerLabel.Text = 'fy';
            app.fySpinner = uispinner(app.UIFigure);
            app.fySpinner.Step = 0.05;
            app.fySpinner.ValueChangingFcn = createCallbackFcn(app, @fySpinnerValueChanging, true);
            app.fySpinner.Limits = [-10 10];
            app.fySpinner.ValueDisplayFormat = '%5.2f U';
            app.fySpinner.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Spinner

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end