classdef Tablas1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        uipanel2          matlab.ui.container.Panel
        Info              matlab.ui.control.Label
        BuscarMatricula   matlab.ui.control.Button
        IngresaMatricula  matlab.ui.control.EditField
        uipanel1          matlab.ui.container.Panel
        Base              matlab.ui.control.Table
        Anadir            matlab.ui.control.Button
    end

    
    properties (Access = private)
        DatosBase %Copia de la info de la tabla
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Inicializamos la app con 4 filas y 6 columnas vacías y a cada
            %columna le aplicamos un formato específico.
            app.Base.ColumnFormat = {'char','char','char',{'Programación','Física'},...
                'numeric','logical'};
            app.Base.Data = cell(4,6);

        end

        % Button pushed function: Anadir
        function Anadir_Callback(app, event)
            [m, n] = size(app.Base.Data);   %Recogemos las dimensiones actuales de la tabla.
            app.DatosBase = app.Base.Data; %Guardamos la información actual de la tabla.
            d = cell(m + 1, n);    %Creamos un arreglo de celdas vacías con una fila extra de la tabla.
            d(1:m, 1:n) = app.DatosBase;   %Metemos la información guardada en el arreglo de celdas nuevo.
            app.Base.Data = d;      %Sobreescribimos la tabla con el nuevo arreglo extendido.
        end

        % Button pushed function: BuscarMatricula
        function BuscarMatricula_Callback(app, event)
            app.DatosBase = app.Base.Data; %Guardamos la información actual de la tabla.
            matricula = cellstr(app.IngresaMatricula.Value); %Recogemos la matrícula ingresada.
            [m, ~] = find(strcmp(app.DatosBase,matricula) == 1); %Localizamos la fila de la tabla que contiene a la matrícula ingresada.
            
            if size(m) == [0, 1] %Si no se encuentra la matrícula, mensaje de error.
                set(app.Info, 'Text', 'Alumno no encontrado', 'FontSize', 24);
            else
                %Si se encuentra un match, recoger toda la información de la fila.
                match = app.DatosBase(m,:); 
                %Aislar cada celda como un texto.
                nombre = match{1}; apellido = match{2};
                materia = match{4}; calificacion = match{5};
                aprobado = str2double(match{6});
                if aprobado == 1
                    txtaprobado = 'Aprobado';
                else
                    txtaprobado = 'No acreditado';
                end
                %Concatenación de textos informativos.
                txt1 = cellstr(['Alumno: ', nombre, blanks(1), apellido]);
                txt2 = cellstr(['Calificación en ', materia, ': ' num2str(calificacion)]);
                txt3 = cellstr(['Estado de acreditación de inglés: ', txtaprobado]);
                set(app.Info, 'Text', [txt1 ; txt2 ; txt3], 'FontSize', 10);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'BaseDatosSolucion';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Búsqueda';
            app.Info = uilabel(app.uipanel2);
            app.Info.Text = '';
            app.BuscarMatricula = uibutton(app.uipanel2, 'push');
            app.BuscarMatricula.ButtonPushedFcn = createCallbackFcn(app, @BuscarMatricula_Callback, true);
            app.BuscarMatricula.Text = 'Buscar por matrícula';
            app.IngresaMatricula = uieditfield(app.uipanel2, 'text');
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Base de datos';
            app.Base = uitable(app.uipanel1);
            app.Base.ColumnName = {'Nombre'; 'Apellido'; 'Matrícula'; 'Materia'; 'Calificación'; 'Acreditó ingles? Sí/No'};
            app.Base.ColumnEditable = [true true true true true true];
            app.Anadir = uibutton(app.uipanel1, 'push');
            app.Anadir.ButtonPushedFcn = createCallbackFcn(app, @Anadir_Callback, true);
            app.Anadir.Text = 'Añadir ';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tablas1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end