classdef Tablas3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EstilosdetablasUIFigure  matlab.ui.Figure
        UITable                  matlab.ui.control.Table
        GenerardatosButton       matlab.ui.control.Button
        RemoverestilosButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        s1 % Description
        s2
        s3
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerardatosButton
        function GenerardatosButtonPushed(app, event)
            m = randi([15,20]);
            app.UITable.Data = randi([50,100],m,4);
            
            [i,j] = find(app.UITable.Data < 60);
            [i2,j2] = find(app.UITable.Data >= 90);
            
            app.s1 = uistyle("BackgroundColor",[1,0.4,0.5],"FontColor",[0.3,0,0],...
                "FontWeight",'bold');
            app.s2 = uistyle("BackgroundColor",[0.4,1,0.5],"FontColor",[0,0.3,0],...
                "FontWeight",'bold');
            addStyle(app.UITable,app.s1,'cell',[i,j]);
            addStyle(app.UITable,app.s2,'cell',[i2,j2]);
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.s3 = uistyle("BackgroundColor",[0.5,0.4,1],"FontColor",[0,0,0.3],...
                "FontWeight",'bold',"HorizontalAlignment",'center');
            addStyle(app.UITable,app.s3,'cell',indices);

        end

        % Button pushed function: RemoverestilosButton
        function RemoverestilosButtonPushed(app, event)
            nestilos = size(app.UITable.StyleConfigurations,1);
            removeStyle(app.UITable,1:nestilos);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EstilosdetablasUIFigure = uifigure('Visible', 'off');
            app.EstilosdetablasUIFigure.Name = 'Estilos de tablas';
            app.UITable = uitable(app.EstilosdetablasUIFigure);
            app.UITable.ColumnName = {'Grupo 1'; 'Grupo 2'; 'Grupo 3'; 'Grupo 4'};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.GenerardatosButton = uibutton(app.EstilosdetablasUIFigure, 'push');
            app.GenerardatosButton.ButtonPushedFcn = createCallbackFcn(app, @GenerardatosButtonPushed, true);
            app.GenerardatosButton.Text = 'Generar datos';
            app.RemoverestilosButton = uibutton(app.EstilosdetablasUIFigure, 'push');
            app.RemoverestilosButton.ButtonPushedFcn = createCallbackFcn(app, @RemoverestilosButtonPushed, true);
            app.RemoverestilosButton.Text = 'Remover estilos';
            % Show the figure after all components are created
            app.EstilosdetablasUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tablas3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EstilosdetablasUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EstilosdetablasUIFigure)
        end
    end
end