classdef Trees < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UIAxes          matlab.ui.control.UIAxes
        Tree            matlab.ui.container.Tree
        NormalesNode    matlab.ui.container.TreeNode
        SenoNode        matlab.ui.container.TreeNode
        CosenoNode      matlab.ui.container.TreeNode
        TangenteNode    matlab.ui.container.TreeNode
        RecprocasNode   matlab.ui.container.TreeNode
        CotangenteNode  matlab.ui.container.TreeNode
        SecanteNode     matlab.ui.container.TreeNode
        CosecanteNode   matlab.ui.container.TreeNode
        SeleccionaunaovariasfuncionesLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        x %Variable independiente X.
        L %Identificador para una leyenda.
    end
    
    methods (Access = private)
        
        function graficar(app,N,entrada)
            
            %Detectamos cuántos nodos se seleccionaron y por cada nodo se almacena toda
            %la información de cada nodo en un renglón de Y
            for i = 1:N
                Y(i,:) = entrada(i).NodeData;
            end
            plot(app.UIAxes,app.x,Y); %Graficamos X contra la matriz de Y.
            app.L = legend(app.UIAxes,entrada(:).Text); %Creamos una leyenda por cada gráfica.
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Al abrir la GUI creamos un vector linealmente espaciado de 0 a 360 que será la variable independiente.
            app.x = linspace(0,360,361);

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes; %Obtenemos el o los nodos seleccionados.
                     
            if size(selectedNodes,1) == 1 %Si sólo se seleccionó un nodo...
                switch selectedNodes.Text
                    case 'Normales' %Si se seleccionó este padre, selecciona sus hijos.
                        app.Tree.SelectedNodes(1) = app.SenoNode;
                        app.Tree.SelectedNodes(2) = app.CosenoNode;
                        app.Tree.SelectedNodes(3) = app.TangenteNode;
                        selectedNodes = app.Tree.SelectedNodes;
                        graficar(app,3,selectedNodes); %Mandamos a llamar a la función "graficar"
                    case 'Recíprocas' %Si se seleccionó este padre, selecciona sus hijos.
                        app.Tree.SelectedNodes(1) = app.CotangenteNode;
                        app.Tree.SelectedNodes(2) = app.SecanteNode;
                        app.Tree.SelectedNodes(3) = app.CosecanteNode;
                        selectedNodes = app.Tree.SelectedNodes;
                        graficar(app,3,selectedNodes); %Mandamos a llamar a la función "graficar"
                    otherwise %Si se seleccionó cualquier otro nodo individual...
                        graficar(app,1,selectedNodes);
                end
            else %Si se seleccionó más de un nodo...
                N = size(selectedNodes,1); %Obtenemos el número de nodos seleccionados.
                graficar(app,N,selectedNodes); %Mandamos a llamar a la función "graficar"
                
            end
            
            
        end

        % Node expanded function: Tree
        function TreeNodeExpanded(app, event)
            node = event.Node;
            
            %Si se expande un nodo, aplicar formato a Axes.
            set(app.UIAxes,'XGrid','on','YGrid','on','XLim',[0,360],...
                'YLim',[-2,2],'XTick', 0:45:360,...
                'XTickLabel',{'0','\pi/4','\pi/2','3\pi/4','\pi',...
                '5\pi/4','3\pi/2','7\pi/4','2\pi'});
        end

        % Node collapsed function: Tree
        function TreeNodeCollapsed(app, event)
            node = event.Node;
            %Si se colapsa un nodo, borra cualquier gráfica y leyenda.
            plot(app.UIAxes,0,0);
            delete(app.L)
        end

        % Node text changed function: Tree
        function TreeNodeTextChanged(app, event)
            node = event.Node;
            %Si se cambia el texto de cualquier nodo, cambia el título de Axes.
            app.UIAxes.Title.String = 'Has modificado el árbol!';
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Funciones trigonométricas')
            app.Tree = uitree(app.UIFigure);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.NodeExpandedFcn = createCallbackFcn(app, @TreeNodeExpanded, true);
            app.Tree.NodeCollapsedFcn = createCallbackFcn(app, @TreeNodeCollapsed, true);
            app.Tree.NodeTextChangedFcn = createCallbackFcn(app, @TreeNodeTextChanged, true);
            app.Tree.Editable = 'on';
            app.NormalesNode = uitreenode(app.Tree);
            app.NormalesNode.Icon = 'ON.png';
            app.NormalesNode.Text = 'Normales';
            app.SenoNode = uitreenode(app.NormalesNode);
            app.SenoNode.NodeData = [0 0.0174524064372835 0.034899496702501 0.0523359562429438 0.0697564737441253 0.0871557427476582 0.104528463267653 0.121869343405147 0.139173100960065 0.156434465040231 0.17364817766693 0.190808995376545 0.207911690817759 0.224951054343865 0.241921895599668 0.258819045102521 0.275637355816999 0.292371704722737 0.309016994374947 0.325568154457157 0.342020143325669 0.3583679495453 0.374606593415912 0.390731128489274 0.4067366430758 0.422618261740699 0.438371146789077 0.453990499739547 0.469471562785891 0.484809620246337];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
            app.SenoNode.Text = 'Seno';
            app.CosenoNode = uitreenode(app.NormalesNode);
            app.CosenoNode.NodeData = [1 0.999847695156391 0.999390827019096 0.998629534754574 0.997564050259824 0.996194698091746 0.994521895368273 0.992546151641322 0.99026806874157 0.987688340595138 0.984807753012208 0.981627183447664 0.978147600733806 0.974370064785235 0.970295726275996 0.965925826289068 0.961261695938319 0.956304755963035 0.951056516295154 0.945518575599317 0.939692620785908 0.933580426497202 0.927183854566787 0.92050485345244 0.913545457642601 0.90630778703665 0.898794046299167 0.891006524188368 0.882947592858927 0.874619707139396];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
            app.CosenoNode.Text = 'Coseno';
            app.TangenteNode = uitreenode(app.NormalesNode);
            app.TangenteNode.NodeData = [0 0.0174550649282176 0.0349207694917477 0.0524077792830412 0.0699268119435104 0.087488663525924 0.105104235265676 0.122784560902905 0.140540834702391 0.158384440324536 0.176326980708465 0.194380309137718 0.212556561670022 0.230868191125563 0.249328002843181 0.267949192431123 0.286745385758808 0.30573068145866 0.324919696232906 0.344327613289665 0.363970234266202 0.383864035035416 0.404026225835157 0.424474816209605 0.445228685308536 0.466307658154999 0.487732588565861 0.509525449494429 0.531709431661479 0.554309051452769];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
            app.TangenteNode.Text = 'Tangente';
            app.RecprocasNode = uitreenode(app.Tree);
            app.RecprocasNode.Icon = 'OFF.png';
            app.RecprocasNode.Text = 'Recíprocas';
            app.CotangenteNode = uitreenode(app.RecprocasNode);
            app.CotangenteNode.NodeData = [Inf 57.2899616307594 28.6362532829156 19.0811366877282 14.3006662567119 11.4300523027613 9.51436445422259 8.14434642797459 7.11536972238421 6.31375151467504 5.67128181961771 5.14455401597031 4.70463010947845 4.33147587428416 4.01078093353584 3.73205080756888 3.48741444384091 3.27085261848414 3.07768353717525 2.90421087767582 2.74747741945462 2.6050890646938 2.4750868534163 2.35585236582375 2.24603677390422 2.14450692050956 2.0503038415793 1.96261050550515 1.88072646534633 1.80404775527142];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
            app.CotangenteNode.Text = 'Cotangente';
            app.SecanteNode = uitreenode(app.RecprocasNode);
            app.SecanteNode.NodeData = [1 1.00015232804391 1.00060954429882 1.00137234599792 1.00244189808117 1.00381983754335 1.00550827956352 1.00750982545885 1.00982757251862 1.012465125788 1.01542661188575 1.01871669495521 1.02234059486503 1.02630410779339 1.0306136293499 1.03527618041008 1.0402994358616 1.04569175648715 1.05146222423827 1.05762068118667 1.06417777247591 1.07114499363703 1.07853474267758 1.0863603774053 1.09463627850605 1.10337791896249 1.11260194047519 1.12232623763436 1.13257005068904 1.14335406787332];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
            app.SecanteNode.Text = 'Secante';
            app.CosecanteNode = uitreenode(app.RecprocasNode);
            app.CosecanteNode.NodeData = [Inf 57.2986884985502 28.6537083478438 19.1073226092974 14.3355870262037 11.4737132456699 9.56677223350563 8.20550904812508 7.18529653432772 6.39245322149966 5.75877048314363 5.24084306416785 4.80973434474413 4.4454114825858 4.13356549443875 3.86370330515627 3.6279552785433 3.42030361983327 3.23606797749979 3.07155348675724 2.92380440016309 2.79042810962534 2.66946716255401 2.55930466524745 2.45859333557424 2.3662015831525 2.28117203270486 2.20268926458527 2.13005446818951 2.06266533962731];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
            app.CosecanteNode.Text = 'Cosecante';
            app.SeleccionaunaovariasfuncionesLabel = uilabel(app.UIFigure);
            app.SeleccionaunaovariasfuncionesLabel.Text = {'Selecciona una o'; ' varias funciones'; ''};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Trees

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end